classdef ImageESR < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ImageESRUIFigure        matlab.ui.Figure
        VxLabel                 matlab.ui.control.Label
        minVx                   matlab.ui.control.NumericEditField
        maxVx                   matlab.ui.control.NumericEditField
        NVx                     matlab.ui.control.NumericEditField
        maxVy                   matlab.ui.control.NumericEditField
        NVy                     matlab.ui.control.NumericEditField
        VyLabel                 matlab.ui.control.Label
        minVy                   matlab.ui.control.NumericEditField
        maxVz                   matlab.ui.control.NumericEditField
        NVz                     matlab.ui.control.NumericEditField
        VzLabel                 matlab.ui.control.Label
        minVz                   matlab.ui.control.NumericEditField
        bFixVx                  matlab.ui.control.CheckBox
        bFixVy                  matlab.ui.control.CheckBox
        bFixVz                  matlab.ui.control.CheckBox
        ScanESRButton           matlab.ui.control.Button
        SaveButton              matlab.ui.control.Button
        FixVx                   matlab.ui.control.NumericEditField
        FixVy                   matlab.ui.control.NumericEditField
        FixVz                   matlab.ui.control.NumericEditField
        STOPButton              matlab.ui.control.Button
        DirectionDropDownLabel  matlab.ui.control.Label
        DirectionDropDown       matlab.ui.control.DropDown
    end

    methods (Access = private)

        % Button pushed function: ScanESRButton
        function ScanESRButtonPushed(app, event)
            global gESR
            if isfield(gESR,'bGo')&&gESR.bGo
                disp('ImageESR thinks there is already a process running. Please hit STOP.')
                return
            end
            
            gESR.minVx=app.minVx.Value;
            gESR.minVy=app.minVy.Value;
            gESR.minVz=app.minVz.Value;
            
            gESR.maxVx=app.maxVx.Value;
            gESR.maxVy=app.maxVy.Value;
            gESR.maxVz=app.maxVz.Value;
            
            gESR.NVx=app.NVx.Value;
            gESR.NVy=app.NVy.Value;
            gESR.NVz=app.NVz.Value;
            
            gESR.bFixVx=app.bFixVx.Value;
            gESR.bFixVy=app.bFixVy.Value;
            gESR.bFixVz=app.bFixVz.Value;
            
            gESR.FixVx=app.FixVx.Value;
            gESR.FixVy=app.FixVy.Value;
            gESR.FixVz=app.FixVz.Value;


            gESR.direction=app.DirectionDropDown.Value;
            if gESR.NVx ~= gESR.NVy && strcmp(gESR.direction,'Diagonal XY')
                error('NVx and NVy do not agree for diagonal sequence. Please make them agree.')
            end
            gESR.bGo=1;
            ImageESRFunctionPool('Run');
        end

        % Button pushed function: STOPButton
        function STOPButtonPushed(app, event)
            global gESR
            gESR.bGo=0;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ImageESRUIFigure = uifigure;
            app.ImageESRUIFigure.Name = 'ImageESR';
            app.VxLabel = uilabel(app.ImageESRUIFigure);
            app.VxLabel.Text = 'Vx';
            app.minVx = uieditfield(app.ImageESRUIFigure, 'numeric');
            app.minVx.Limits = [-5 5];
            app.minVx.Value = -0.5;
            app.maxVx = uieditfield(app.ImageESRUIFigure, 'numeric');
            app.maxVx.Limits = [-5 5];
            app.maxVx.Value = 0.5;
            app.NVx = uieditfield(app.ImageESRUIFigure, 'numeric');
            app.NVx.Limits = [0 Inf];
            app.NVx.Value = 11;
            app.maxVy = uieditfield(app.ImageESRUIFigure, 'numeric');
            app.maxVy.Limits = [-5 5];
            app.maxVy.Value = 0.5;
            app.NVy = uieditfield(app.ImageESRUIFigure, 'numeric');
            app.NVy.Limits = [0 Inf];
            app.NVy.Value = 11;
            app.VyLabel = uilabel(app.ImageESRUIFigure);
            app.VyLabel.Text = 'Vy';
            app.minVy = uieditfield(app.ImageESRUIFigure, 'numeric');
            app.minVy.Limits = [-5 5];
            app.minVy.Value = -0.5;
            app.maxVz = uieditfield(app.ImageESRUIFigure, 'numeric');
            app.maxVz.Limits = [0 100];
            app.maxVz.Value = 100;
            app.NVz = uieditfield(app.ImageESRUIFigure, 'numeric');
            app.NVz.Limits = [0 Inf];
            app.NVz.Value = 11;
            app.VzLabel = uilabel(app.ImageESRUIFigure);
            app.VzLabel.Text = 'Vz';
            app.minVz = uieditfield(app.ImageESRUIFigure, 'numeric');
            app.minVz.Limits = [0 100];
            app.bFixVx = uicheckbox(app.ImageESRUIFigure);
            app.bFixVx.Text = 'Fix Vx';
            app.bFixVy = uicheckbox(app.ImageESRUIFigure);
            app.bFixVy.Text = 'Fix Vy';
            app.bFixVz = uicheckbox(app.ImageESRUIFigure);
            app.bFixVz.Text = 'Fix Vz';
            app.ScanESRButton = uibutton(app.ImageESRUIFigure, 'push');
            app.ScanESRButton.ButtonPushedFcn = createCallbackFcn(app, @ScanESRButtonPushed, true);
            app.ScanESRButton.Text = 'Scan ESR';
            app.SaveButton = uibutton(app.ImageESRUIFigure, 'push');
            app.SaveButton.Text = 'Save';
            app.FixVx = uieditfield(app.ImageESRUIFigure, 'numeric');
            app.FixVx.Limits = [-5 5];
            app.FixVy = uieditfield(app.ImageESRUIFigure, 'numeric');
            app.FixVy.Limits = [-5 5];
            app.FixVz = uieditfield(app.ImageESRUIFigure, 'numeric');
            app.FixVz.Limits = [0 100];
            app.FixVz.Value = 50;
            app.STOPButton = uibutton(app.ImageESRUIFigure, 'push');
            app.STOPButton.ButtonPushedFcn = createCallbackFcn(app, @STOPButtonPushed, true);
            app.STOPButton.Text = 'STOP';
            app.DirectionDropDownLabel = uilabel(app.ImageESRUIFigure);
            app.DirectionDropDownLabel.Text = 'Direction';
            app.DirectionDropDown = uidropdown(app.ImageESRUIFigure);
            app.DirectionDropDown.Items = {'Cartesian', 'Diagonal XY'};
            app.DirectionDropDown.Value = 'Cartesian';
        end
    end

    methods (Access = public)

        % Construct app
        function app = ImageESR

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ImageESRUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ImageESRUIFigure)
        end
    end
end