classdef CtrlGUI_LS336_Sub < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SendComandsDirectlyUIFigure  matlab.ui.Figure
        ResponsePanel                matlab.ui.container.Panel
        ResponseTextArea             matlab.ui.control.TextArea
        ClearResponseButton          matlab.ui.control.Button
        CommandQueryPanel            matlab.ui.container.Panel
        CommandEditField             matlab.ui.control.EditField
        SendCommandButton            matlab.ui.control.Button
        SendQueryButton              matlab.ui.control.Button
    end

    properties (Access = public)
        CtrlGUI
        Driver
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, In_CtrlGUI)
            app.CtrlGUI = In_CtrlGUI;
            app.Driver = In_CtrlGUI.Utility.Driver;
            movegui(app.SendComandsDirectlyUIFigure,"center");
        end

        % Close request function: SendComandsDirectlyUIFigure
        function SendComandsDirectlyUIFigureCloseRequest(app, event)
            app.CtrlGUI.SendCommandDirectlyMenu.Enable = 'on';
            delete(app)
        end

        % Button pushed function: SendCommandButton
        function SendCommandButtonPushed(app, event)
            app.Driver.send_Command(app.CommandEditField.Value)
        end

        % Button pushed function: SendQueryButton
        function SendQueryButtonPushed(app, event)
            [~, response] = app.Driver.send_Query( ...
                app.CommandEditField.Value);
            if isempty(app.ResponseTextArea.Value{end, 1})
                app.ResponseTextArea.Value{end, 1} = char(response);
            else
                app.ResponseTextArea.Value{end + 1, 1} = char(response);
            end
        end

        % Button pushed function: ClearResponseButton
        function ClearResponseButtonPushed(app, event)
            app.ResponseTextArea.Value = '';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SendComandsDirectlyUIFigure = uifigure('Visible', 'off');
            app.SendComandsDirectlyUIFigure.Name = 'Send Comands Directly';
            app.SendComandsDirectlyUIFigure.CloseRequestFcn = createCallbackFcn(app, @SendComandsDirectlyUIFigureCloseRequest, true);
            app.ResponsePanel = uipanel(app.SendComandsDirectlyUIFigure);
            app.ResponsePanel.Title = 'Response';
            app.ResponseTextArea = uitextarea(app.ResponsePanel);
            app.ResponseTextArea.Editable = 'off';
            app.ClearResponseButton = uibutton(app.ResponsePanel, 'push');
            app.ClearResponseButton.ButtonPushedFcn = createCallbackFcn(app, @ClearResponseButtonPushed, true);
            app.ClearResponseButton.Text = 'Clear Response';
            app.CommandQueryPanel = uipanel(app.SendComandsDirectlyUIFigure);
            app.CommandQueryPanel.Title = 'Command / Query';
            app.CommandEditField = uieditfield(app.CommandQueryPanel, 'text');
            app.CommandEditField.Value = '*IDN?';
            app.SendCommandButton = uibutton(app.CommandQueryPanel, 'push');
            app.SendCommandButton.ButtonPushedFcn = createCallbackFcn(app, @SendCommandButtonPushed, true);
            app.SendCommandButton.Text = 'Send Command';
            app.SendQueryButton = uibutton(app.CommandQueryPanel, 'push');
            app.SendQueryButton.ButtonPushedFcn = createCallbackFcn(app, @SendQueryButtonPushed, true);
            app.SendQueryButton.Text = 'Send Query';
            % Show the figure after all components are created
            app.SendComandsDirectlyUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CtrlGUI_LS336_Sub(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SendComandsDirectlyUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SendComandsDirectlyUIFigure)
        end
    end
end