classdef WidefieldESR < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        WidefieldESRUIFigure         matlab.ui.Figure
        NormalizewrtgreenCheckBox    matlab.ui.control.CheckBox
        MicrowavesettingsPanel       matlab.ui.container.Panel
        FromEditFieldLabel           matlab.ui.control.Label
        FromEditField                matlab.ui.control.NumericEditField
        ToEditFieldLabel             matlab.ui.control.Label
        ToEditField                  matlab.ui.control.NumericEditField
        NEditFieldLabel              matlab.ui.control.Label
        NEditField                   matlab.ui.control.NumericEditField
        PowerdBmEditFieldLabel       matlab.ui.control.Label
        PowerdBmEditField            matlab.ui.control.NumericEditField
        CamerasettingsPanel          matlab.ui.container.Panel
        FPSLabel                     matlab.ui.control.Label
        FramerateEditField           matlab.ui.control.NumericEditField
        UpdatesEditFieldLabel        matlab.ui.control.Label
        UpdatesEditField             matlab.ui.control.NumericEditField
        FastestCheckBox              matlab.ui.control.CheckBox
        AverageEditFieldLabel        matlab.ui.control.Label
        AverageEditField             matlab.ui.control.NumericEditField
        RunButton                    matlab.ui.control.Button
        STOPButton                   matlab.ui.control.Button
        IterationEditFieldLabel      matlab.ui.control.Label
        IterationEditField           matlab.ui.control.NumericEditField
        PlotSpectraExternallyButton  matlab.ui.control.Button
        UIAxes                       matlab.ui.control.UIAxes
        SpectrumAxes                 matlab.ui.control.UIAxes
        ImageAxes                    matlab.ui.control.UIAxes
        PlotImageExternallyButton    matlab.ui.control.Button
        DimensionListBoxLabel        matlab.ui.control.Label
        DimensionListBox             matlab.ui.control.ListBox
        XSliderLabel                 matlab.ui.control.Label
        XSlider                      matlab.ui.control.Slider
        YLabel                       matlab.ui.control.Label
        YSlider                      matlab.ui.control.Slider
        PlotstartEditFieldLabel      matlab.ui.control.Label
        PlotstartEditField           matlab.ui.control.NumericEditField
        PlotendEditFieldLabel        matlab.ui.control.Label
        PlotendEditField             matlab.ui.control.NumericEditField
        PlotEndFreq                  matlab.ui.control.NumericEditField
        PlotStartFreq                matlab.ui.control.NumericEditField
        CoordinatesLabel             matlab.ui.control.Label
    end

    
    properties (Access = public)
    end
    
    methods (Access = private)
        
        
        function InitParams(app)
            global gWide gSG
            gWide.bOn=1;
            gWide.From=app.FromEditField.Value*1e9;
            gWide.To=app.ToEditField.Value*1e9;
            gWide.N=app.NEditField.Value;
            gWide.SweepParam=gWide.From:(gWide.To-gWide.From)/(gWide.N-1):gWide.To;
            gSG.Pow = app.PowerdBmEditField.Value;
            gWide.AcquisitionFrameRate=app.FramerateEditField.Value;
            gWide.Average=app.AverageEditField.Value;
            gWide.UpdateTime=app.UpdatesEditField.Value;
            %lf.set(PrincetonInstruments.LightField.AddIns.TriggerResponse,2);
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%FIX THIS%%%%%%%%%%%%%%%%%%%%%%%%
            % Prepare sweep vector on analog output of NI DAQ
            gSG.sweepDev=(gWide.To-gWide.From)/2;
            gSG.Freq=(gWide.To+gWide.From)/2;
            
            % If the boolean NORMALIZE is true, then prepare Thorlabs software to read photodiode approximately as fast as exposure timescale
            gSG.bfixedPow=1;
            gSG.bfixedFreq=0;
            gSG.bMod='Sweep';
            gSG.bModSrc='External';
        end
        
        function DisplaySpectra(app)
            global gWide
            x=round(app.XSlider.Value);
            y=round(app.YSlider.Value);
            dimension=app.DimensionListBox.Value;
            if gWide.PlotEnd==inf
                PlotEnd=length(gWide.SweepParam);
            else
                PlotEnd=gWide.PlotEnd;
            end
            if gWide.PlotStart>gWide.PlotEnd || gWide.PlotStart>length(gWide.SweepParam)
                error('Plotting out of bounds')
            end
            mw=gWide.SweepParam(gWide.PlotStart:PlotEnd)/1e9;
            cla(app.SpectrumAxes);
            switch dimension
                case 'Point'
                    data=squeeze(gWide.signal(x,y,gWide.PlotStart:PlotEnd));
                    plot(app.SpectrumAxes, mw,data)
                    if min(data)==max(data)
                        ylim(app.SpectrumAxes,[min(data)-1 max(data)+1])
                    else
                        ylim(app.SpectrumAxes,[min(data) max(data)])
                    end
                    
                    ylabel(app.SpectrumAxes,'Counts')
                    app.SpectrumAxes.YDir = 'normal';
                case 'Vertical'
                    data=squeeze(gWide.signal(x,:,gWide.PlotStart:PlotEnd));
                    
                    for i=1:size(data,1)
                        data(i,:)=data(i,:)-mean(data(i,:));
                        data(i,:)=data(i,:)/std(data(i,:));
                    end
                    
                    imagesc(app.SpectrumAxes, mw, 1:size(data,1),data,[min(data,[],'all') max(data,[],'all')])
                    
                    ylim(app.SpectrumAxes,[1 size(data,1)])
                    ylabel(app.SpectrumAxes,'Pixel')
                case 'Horizontal'
                    data=squeeze(gWide.signal(:,y,gWide.PlotStart:PlotEnd));
                    
                    for i=1:size(data,1)
                        data(i,:)=data(i,:)-mean(data(i,:));
                        data(i,:)=data(i,:)/std(data(i,:));
                    end
                    
                    
                    imagesc(app.SpectrumAxes, mw, 1:size(data,1),data,[min(data,[],'all') max(data,[],'all')])
                    ylim(app.SpectrumAxes,[1 size(data,1)])
                    ylabel(app.SpectrumAxes,'Pixel')
            end
            xlim(app.SpectrumAxes,[mw(1) mw(end)])
            DisplayImage(app)
        end
        
        function sigDatum=ProcessData(~,AA)
            global gWide
            
            N=gWide.N;
            sigDatum=zeros(size(AA,1),size(AA,2),N);
            
            for k=1:size(sigDatum,3)
                sigDatum(:,:,k)=sum(AA(:,:,k:N:end),3);
            end
            
            % if ~mod(gWide.iAverage,2)
            %      sigDatum=flip(sigDatum,3);
            % end
            sigDatum=sigDatum/(gWide.samps/gWide.N);
        end
        
        function vec=MakeSweepVector(~)
            global gWide 
            %i=gWide.iAverage;
            vec=-1:(2/(gWide.N-1)):1;
            % if ~mod(i,2)
            %     vec=-vec;
            % end
            sampsTemp=gWide.AcquisitionFrameRate*gWide.UpdateTime;
            vec=repmat(vec,1,ceil(sampsTemp/gWide.N));
            gWide.samps=length(vec);
        end
        
        function DisplayImage(app)
            global gWide
            cla(app.ImageAxes)
            if strcmp(gWide.dimension,'Difference')
                if gWide.PlotStart>gWide.PlotEnd || gWide.PlotStart>length(gWide.SweepParam)
                    error('Plotting out of bounds')
                end
                if gWide.PlotEnd==inf
                    PlotEnd=length(gWide.SweepParam);
                else 
                    PlotEnd=gWide.PlotEnd;
                end
                
                data=squeeze(gWide.signal(:,:,PlotEnd))'-squeeze(gWide.signal(:,:,gWide.PlotStart))';
            else
                data=squeeze(gWide.signal(:,:,2))';
            end
            
            imagesc(app.ImageAxes,data);
            xlim(app.ImageAxes,[1 size(data,2)])
            ylim(app.ImageAxes,[1 size(data,1)])
            pbaspect(app.ImageAxes,[1 size(data,1)/size(data,2) 1]);
            
            hold(app.ImageAxes,'on')
            switch gWide.dimension
                case 'Point'
                    x=round(app.XSlider.Value);
                    y=round(app.YSlider.Value);
                    line(app.ImageAxes,[x x], [1 size(data,1)], 'Color', 'r');
                    line(app.ImageAxes,[1 size(data,2)],[y y], 'Color', 'r');
                case 'Vertical'
                    x=round(app.XSlider.Value);
                    line(app.ImageAxes,[x x], [1 size(data,1)], 'Color', 'r');
                case 'Horizontal'
                    y=round(app.YSlider.Value);
                    line(app.ImageAxes,[1 size(data,2)],[y y], 'Color', 'r');
                case 'Difference'
                    
            end


            set(app.ImageAxes,'colorscale','log')
            colorbar(app.ImageAxes)
        end
    end
    
    methods (Access = public)
        
        function UpdateSliders(app,xlim,ylim)
            app.XSlider.Limits=[1 xlim];
            app.YSlider.Limits=[1 ylim];
        end
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global lf
            Setup_LightField_Environment;
            lf = lfm(true); % prepares an instance of Lightfield; VISIBLE indicates that Lightfield will open alongside the MATLAB workstation.
            lf.load_experiment('rt2_wide');
            LoadNIDAQmx;% initialize NIDAQ
            % load SRS SG384 DLL
            SignalGeneratorFunctionPool('Init',PortMap('SG com'));
            % specify pixel-to-micron conversion
            
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            global gWide gSG lf hCPS
            if isfield(gWide,'bOn')
                if gWide.bOn
                    disp('I think there is already a sequence in process. Please hit STOP.')
                    return
                end
            end
            
            InitParams(app);
            
            %XSliderValueChanged(app,event);
            try
                disp('Commencing widefield ESR...')
                
                % TURN ON AOM
                PBFunctionPool('PBON',2^SequencePool('PBDictionary','AOM'));
                % TURN ON SRS
                SignalGeneratorFunctionPool('WriteFreq');
                SignalGeneratorFunctionPool('WritePow');
                gSG.sweepRate=gWide.AcquisitionFrameRate/gWide.N;
                SignalGeneratorFunctionPool('SetMod');
                gSG.bOn=1; SignalGeneratorFunctionPool('RFOnOff');
                i=1;
                while i<=gWide.Average
                    
                    % PREPARE PULSE TRAIN / ANALOG VOLTAGE
                    %%%%% Pulse Train %%%%%
                    %%%%% Analog write %%%%
            
                    gWide.iAverage=i;
                    app.IterationEditField.Value=i;
                    % Prepare triggering signal on DAQ based on max frame rate
                    [vec]=MakeSweepVector(app);
                    [~, hCPS.hPulse] = DigPulseTrainCont(gWide.AcquisitionFrameRate,0.5,gWide.samps);
                    [~, hCPS.hScan ] = DAQmxFunctionPool('WriteAnalogVoltage',PortMap('SG ext mod'),vec, gWide.samps,gWide.AcquisitionFrameRate);
                    lf.set_frames(gWide.samps); % max is 10^9
                    % PREPARE LIGHTFIELD TO SEE TRIGGERS
                    lf.experiment.Acquire;
                    % BEGIN TRIGGERS
                    status = DAQmxStartTask(hCPS.hScan);  DAQmxErr(status);
                    status = DAQmxStartTask(hCPS.hPulse);    DAQmxErr(status);
                    DAQmxWaitUntilTaskDone(hCPS.hPulse,gWide.samps*gWide.AcquisitionFrameRate*1.1);
                    
                    DAQmxStopTask(hCPS.hScan);
                    DAQmxStopTask(hCPS.hPulse);
                    while ~lf.experiment.IsReadyToRun
                        pause(1);
                    end  
                    raw=lf.readout;
                    while ~lf.experiment.IsReadyToRun
                        pause(1);
                    end 
                    %TemporarySave(BackupFile);
                    DAQmxClearTask(hCPS.hPulse);
                    DAQmxClearTask(hCPS.hScan);
            
                    if size(raw,3)~=gWide.samps
                        disp(strcat('Size of data: ',num2str(size(gWide.raw,3))));
                        disp(strcat('Expected samples: ',num2str(gWide.samps)));
                        disp('Not all data was acquired. Redoing run.')
                        drawnow;
                        if ~gWide.bOn
                            disp('Aborting widefield ESR...')
                            break;
                        end
                        continue
                    else
                        if i==1
                            gWide.signal=ProcessData(app,raw);
                        else
                            gWide.signal=(((i-1)*gWide.signal)+ProcessData(app,raw))/i;
                        end
                    end
                    UpdateSliders(app,size(gWide.signal,1),size(gWide.signal,2));
                    DisplaySpectra(app);
                    drawnow;
                    % DELETE EXTRANEOUS FILES
            %        file=lf.get(PrincetonInstruments.LightField.AddIns.ExperimentSettings.AcquisitionOutputFilesResult);
            %        delete file
                    if ~gWide.bOn
                        disp('Aborting widefield ESR...')
                        break;
                    end
                    i=i+1;
                end
            catch ME
                KillAllTasks;
                gSG.bOn=0; SignalGeneratorFunctionPool('RFOnOff');
                rethrow(ME);
            end
            gSG.bOn=0; SignalGeneratorFunctionPool('RFOnOff');
            % DISPLAY PLOTS
            
            disp('Widefield ESR completed!')  
            gWide.bOn=0;
        end

        % Value changed function: DimensionListBox
        function DimensionListBoxValueChanged(app, event)
            XSliderValueChanged(app,event);
            switch app.DimensionListBox.Value
                case 'Point'
                    app.XSlider.Enable='on';
                    app.YSlider.Enable='on';
                case 'Vertical'
                    app.XSlider.Enable='on';
                    app.YSlider.Enable='off';
                case 'Horizontal'
                    app.XSlider.Enable='off';
                    app.YSlider.Enable='on';
            end
        end

        % Value changed function: XSlider
        function XSliderValueChanged(app, event)
            global gWide
            gWide.dimension=app.DimensionListBox.Value;
            x=round(app.XSlider.Value);
            y=round(app.YSlider.Value);
            gWide.PlotStart = app.PlotstartEditField.Value;
            gWide.PlotEnd = app.PlotendEditField.Value;
            app.CoordinatesLabel.Text=strcat('Coordinates: (',num2str(x),', ',num2str(y),')');
            if strcmp(app.DimensionListBox.Value,'Difference')
                DisplayImage(app);
            else
                DisplaySpectra(app);
            end
        end

        % Value changed function: YSlider
        function YSliderValueChanged(app, event)
            XSliderValueChanged(app,event);    
        end

        % Value changed function: FastestCheckBox
        function FastestCheckBoxValueChanged(app, event)
            global lf
            value = app.FastestCheckBox.Value;
            if value
                word='off';
                set(app.FramerateEditField,'Value',lf.get(PrincetonInstruments.LightField.AddIns.CameraSettings.AcquisitionFrameRate));
            else
                word='on';
            end
            set(app.FramerateEditField,'enable',word);
        end

        % Button pushed function: STOPButton
        function STOPButtonPushed(app, event)
            global gWide
            gWide.bOn=0;
        end

        % Close request function: WidefieldESRUIFigure
        function WidefieldESRUIFigureCloseRequest(app, event)
            global lf
             selection = questdlg('Close Experiment?',...
                     'Close Request Function',...
                     'Yes','Yes, LF crashed','No','Yes');
             switch selection
               case 'Yes'
                 lf.close;
                 delete(app);
               case 'Yes, LF crashed'
                 delete(app);
               case 'No'
                 return
             end
        end

        % Value changed function: PlotstartEditField
        function PlotstartEditFieldValueChanged(app, event)
            global gWide
            
            XSliderValueChanged(app, event);
            app.PlotStartFreq.Visible='on';
            app.PlotEndFreq.Visible='on';
            app.PlotStartFreq.Value=gWide.SweepParam(app.PlotstartEditField.Value)/1e9;
            if app.PlotendEditField.Value==Inf
                app.PlotEndFreq.Value=gWide.SweepParam(end)/1e9;
            else
                app.PlotEndFreq.Value=gWide.SweepParam(app.PlotendEditField.Value)/1e9;
            end
        end

        % Value changed function: PlotendEditField
        function PlotendEditFieldValueChanged(app, event)
            PlotstartEditFieldValueChanged(app,event);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.WidefieldESRUIFigure = uifigure;
            app.WidefieldESRUIFigure.Name = 'WidefieldESR';
            app.WidefieldESRUIFigure.CloseRequestFcn = createCallbackFcn(app, @WidefieldESRUIFigureCloseRequest, true);
            app.NormalizewrtgreenCheckBox = uicheckbox(app.WidefieldESRUIFigure);
            app.NormalizewrtgreenCheckBox.Text = 'Normalize w.r.t. green';
            app.MicrowavesettingsPanel = uipanel(app.WidefieldESRUIFigure);
            app.MicrowavesettingsPanel.Title = 'Microwave settings';
            app.FromEditFieldLabel = uilabel(app.MicrowavesettingsPanel);
            app.FromEditFieldLabel.Text = 'From';
            app.FromEditField = uieditfield(app.MicrowavesettingsPanel, 'numeric');
            app.FromEditField.Limits = [1.4 4];
            app.FromEditField.Value = 2.861;
            app.ToEditFieldLabel = uilabel(app.MicrowavesettingsPanel);
            app.ToEditFieldLabel.Text = 'To';
            app.ToEditField = uieditfield(app.MicrowavesettingsPanel, 'numeric');
            app.ToEditField.Limits = [1.4 4];
            app.ToEditField.Value = 2.88;
            app.NEditFieldLabel = uilabel(app.MicrowavesettingsPanel);
            app.NEditFieldLabel.Text = 'N';
            app.NEditField = uieditfield(app.MicrowavesettingsPanel, 'numeric');
            app.NEditField.Limits = [2 Inf];
            app.NEditField.Value = 21;
            app.PowerdBmEditFieldLabel = uilabel(app.MicrowavesettingsPanel);
            app.PowerdBmEditFieldLabel.Text = 'Power (dBm)';
            app.PowerdBmEditField = uieditfield(app.MicrowavesettingsPanel, 'numeric');
            app.PowerdBmEditField.Limits = [-Inf -10];
            app.PowerdBmEditField.Value = -10;
            app.CamerasettingsPanel = uipanel(app.WidefieldESRUIFigure);
            app.CamerasettingsPanel.Title = 'Camera settings';
            app.FPSLabel = uilabel(app.CamerasettingsPanel);
            app.FPSLabel.Text = 'FPS';
            app.FramerateEditField = uieditfield(app.CamerasettingsPanel, 'numeric');
            app.FramerateEditField.Limits = [0.1 Inf];
            app.FramerateEditField.Value = 5;
            app.UpdatesEditFieldLabel = uilabel(app.CamerasettingsPanel);
            app.UpdatesEditFieldLabel.Text = 'Update (s)';
            app.UpdatesEditField = uieditfield(app.CamerasettingsPanel, 'numeric');
            app.UpdatesEditField.Limits = [1 Inf];
            app.UpdatesEditField.Value = 10;
            app.FastestCheckBox = uicheckbox(app.CamerasettingsPanel);
            app.FastestCheckBox.ValueChangedFcn = createCallbackFcn(app, @FastestCheckBoxValueChanged, true);
            app.FastestCheckBox.Text = 'Fastest';
            app.AverageEditFieldLabel = uilabel(app.CamerasettingsPanel);
            app.AverageEditFieldLabel.Text = 'Average';
            app.AverageEditField = uieditfield(app.CamerasettingsPanel, 'numeric');
            app.AverageEditField.Limits = [1 Inf];
            app.AverageEditField.Value = 9999;
            app.RunButton = uibutton(app.WidefieldESRUIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.STOPButton = uibutton(app.WidefieldESRUIFigure, 'push');
            app.STOPButton.ButtonPushedFcn = createCallbackFcn(app, @STOPButtonPushed, true);
            app.STOPButton.Text = 'STOP';
            app.IterationEditFieldLabel = uilabel(app.WidefieldESRUIFigure);
            app.IterationEditFieldLabel.Text = 'Iteration';
            app.IterationEditField = uieditfield(app.WidefieldESRUIFigure, 'numeric');
            app.IterationEditField.Editable = 'off';
            app.PlotSpectraExternallyButton = uibutton(app.WidefieldESRUIFigure, 'push');
            app.PlotSpectraExternallyButton.Text = 'Plot externally';
            app.UIAxes = uiaxes(app.WidefieldESRUIFigure);
            title(app.UIAxes, 'Hysteresis')
            xlabel(app.UIAxes, 'Frequency (GHz)')
            ylabel(app.UIAxes, 'Difference')
            app.UIAxes.Visible = 'off';
            app.SpectrumAxes = uiaxes(app.WidefieldESRUIFigure);
            title(app.SpectrumAxes, 'Spectrum')
            xlabel(app.SpectrumAxes, 'Frequency (GHz)')
            app.ImageAxes = uiaxes(app.WidefieldESRUIFigure);
            title(app.ImageAxes, '')
            xlabel(app.ImageAxes, '')
            app.PlotImageExternallyButton = uibutton(app.WidefieldESRUIFigure, 'push');
            app.PlotImageExternallyButton.Text = 'Plot externally';
            app.DimensionListBoxLabel = uilabel(app.WidefieldESRUIFigure);
            app.DimensionListBoxLabel.Text = 'Dimension';
            app.DimensionListBox = uilistbox(app.WidefieldESRUIFigure);
            app.DimensionListBox.Items = {'Point', 'Vertical', 'Horizontal', 'Difference'};
            app.DimensionListBox.ValueChangedFcn = createCallbackFcn(app, @DimensionListBoxValueChanged, true);
            app.DimensionListBox.Value = 'Point';
            app.XSliderLabel = uilabel(app.WidefieldESRUIFigure);
            app.XSliderLabel.Text = 'X';
            app.XSlider = uislider(app.WidefieldESRUIFigure);
            app.XSlider.ValueChangedFcn = createCallbackFcn(app, @XSliderValueChanged, true);
            app.YLabel = uilabel(app.WidefieldESRUIFigure);
            app.YLabel.Text = 'Y';
            app.YSlider = uislider(app.WidefieldESRUIFigure);
            app.YSlider.ValueChangedFcn = createCallbackFcn(app, @YSliderValueChanged, true);
            app.PlotstartEditFieldLabel = uilabel(app.WidefieldESRUIFigure);
            app.PlotstartEditFieldLabel.Text = 'Plot start';
            app.PlotstartEditField = uieditfield(app.WidefieldESRUIFigure, 'numeric');
            app.PlotstartEditField.Limits = [1 Inf];
            app.PlotstartEditField.ValueChangedFcn = createCallbackFcn(app, @PlotstartEditFieldValueChanged, true);
            app.PlotstartEditField.Value = 1;
            app.PlotendEditFieldLabel = uilabel(app.WidefieldESRUIFigure);
            app.PlotendEditFieldLabel.Text = 'Plot end';
            app.PlotendEditField = uieditfield(app.WidefieldESRUIFigure, 'numeric');
            app.PlotendEditField.Limits = [1 Inf];
            app.PlotendEditField.ValueChangedFcn = createCallbackFcn(app, @PlotendEditFieldValueChanged, true);
            app.PlotendEditField.Value = Inf;
            app.CoordinatesLabel = uilabel(app.WidefieldESRUIFigure);
            app.CoordinatesLabel.Text = 'Coordinates';
            app.PlotStartFreq = uieditfield(app.WidefieldESRUIFigure, 'numeric');
            app.PlotStartFreq.Limits = [0 Inf];
            app.PlotStartFreq.Editable = 'off';
            app.PlotStartFreq.Visible = 'off';
            app.PlotEndFreq = uieditfield(app.WidefieldESRUIFigure, 'numeric');
            app.PlotEndFreq.Limits = [0 Inf];
            app.PlotEndFreq.Editable = 'off';
            app.PlotEndFreq.Visible = 'off';
        end
    end

    methods (Access = public)

        % Construct app
        function app = WidefieldESR

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.WidefieldESRUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.WidefieldESRUIFigure)
        end
    end
end