classdef Magnet_DO_NOT_USE < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        ConnectX                  matlab.ui.control.StateButton
        ConnectY                  matlab.ui.control.StateButton
        ConnectZ                  matlab.ui.control.StateButton
        VoltageSetEditFieldLabel  matlab.ui.control.Label
        XVoltageEdit              matlab.ui.control.NumericEditField
        CurrentSetEditFieldLabel  matlab.ui.control.Label
        XCurrentEdit              matlab.ui.control.NumericEditField
        XVSet                     matlab.ui.control.Button
        XISet                     matlab.ui.control.Button
        YVSet                     matlab.ui.control.Button
        YISet                     matlab.ui.control.Button
        XVoltageEditField_2Label  matlab.ui.control.Label
        YVoltageEdit              matlab.ui.control.NumericEditField
        XCurrentEditField_2Label  matlab.ui.control.Label
        YCurrentEdit              matlab.ui.control.NumericEditField
        ZVSet                     matlab.ui.control.Button
        ZISet                     matlab.ui.control.Button
        XVoltageEditField_3Label  matlab.ui.control.Label
        ZVoltageEdit              matlab.ui.control.NumericEditField
        XCurrentEditField_3Label  matlab.ui.control.Label
        ZCurrentEdit              matlab.ui.control.NumericEditField
        VoltageEditFieldLabel     matlab.ui.control.Label
        XVoltage                  matlab.ui.control.NumericEditField
        CurrentEditFieldLabel     matlab.ui.control.Label
        XCurrent                  matlab.ui.control.NumericEditField
        XOutputOnOff              matlab.ui.control.StateButton
        YOutputOnOff              matlab.ui.control.StateButton
        XVoltage_2Label           matlab.ui.control.Label
        YVoltage                  matlab.ui.control.NumericEditField
        XCurrent_2Label           matlab.ui.control.Label
        YCurrent                  matlab.ui.control.NumericEditField
        ZOutputOnOff              matlab.ui.control.StateButton
        XVoltage_3Label           matlab.ui.control.Label
        ZVoltage                  matlab.ui.control.NumericEditField
        XCurrent_3Label           matlab.ui.control.Label
        ZCurrent                  matlab.ui.control.NumericEditField
        ModeEditFieldLabel        matlab.ui.control.Label
        XMode                     matlab.ui.control.EditField
        XMode_3Label              matlab.ui.control.Label
        ZMode                     matlab.ui.control.EditField
        XMode_2Label              matlab.ui.control.Label
        YMode                     matlab.ui.control.EditField
        UpdateDisplayButton       matlab.ui.control.Button
        XLamp                     matlab.ui.control.Lamp
        YLamp                     matlab.ui.control.Lamp
        ZLamp                     matlab.ui.control.Lamp
    end


    properties (Access = private)
        updateTimer % Timer to automatically update the GUI
    end

    methods (Access = private)
    
        function UpdateX(app)
            if not(app.ConnectX.Value)
                app.XCurrent.Value=0;
                app.XVoltage.Value=0;
                app.XMode.Value='';
                app.XMode.BackgroundColor=[0.9373 0.9373 0.9373];
                return
            end
            global keithleyX;
            keithleyX.Query();
            app.XCurrent.Value= keithleyX.current;
            app.XVoltage.Value= keithleyX.voltage;
            if isempty(keithleyX.mode)
                app.XMode.Value= '';
                app.XMode.BackgroundColor=[0.9373 0.9373 0.9373];
            else
                app.XMode.Value= keithleyX.mode;
                switch keithleyX.mode
                    case 'CC'
                        app.XMode.BackgroundColor='green';
                    case 'CV'
                        app.XMode.BackgroundColor='red';
                end
            end
            %app.XCurrentEdit.Value= keithleyX.current;
            %app.XVoltageEdit.Value= keithelyX.voltage;
        end
        
        function UpdateY(app)
            if not(app.ConnectY.Value)
                app.YCurrent.Value=0;
                app.YVoltage.Value=0;
                app.YMode.Value='';
                app.YMode.BackgroundColor=[0.9373 0.9373 0.9373];
                return
            end
            global keithleyY;
            keithleyY.Query();
            app.YCurrent.Value= keithleyY.current;
            app.YVoltage.Value= keithleyY.voltage;
            if isempty(keithleyY.mode)
                app.YMode.Value= '';
                app.YMode.BackgroundColor=[0.9373 0.9373 0.9373];
            else
                app.YMode.Value= keithleyY.mode;
                switch keithleyY.mode
                    case 'CC'
                        app.YMode.BackgroundColor='green';
                    case 'CV'
                        app.YMode.BackgroundColor='red';
                end
            end
            %app.YCurrentEdit.Value= keithleyY.current;
            %app.YVoltageEdit.Value= keithleyY.voltage;
        end
        
        function UpdateZ(app)
            if not(app.ConnectZ.Value)
                app.ZCurrent.Value=0;
                app.ZVoltage.Value=0;
                app.ZMode.Value='';
                app.ZMode.BackgroundColor=[0.9373 0.9373 0.9373];
                return
            end
            global keithleyZ;
            keithleyZ.Query();
            app.ZCurrent.Value= keithleyZ.current;
            app.ZVoltage.Value= keithleyZ.voltage;
            if isempty(keithleyZ.mode)
                app.ZMode.Value= '';
                app.ZMode.BackgroundColor=[0.9373 0.9373 0.9373];
            else
                app.ZMode.Value= keithleyZ.mode;
                switch keithleyZ.mode
                    case 'CC'
                        app.ZMode.BackgroundColor= 'green';
                    case 'CV'
                        app.ZMode.BackgroundColor= 'red';
                end
            end
            %app.ZCurrentEdit.Value= keithleyZ.current;
            %app.ZVoltageEdit.Value= keithleyZ.voltage;
        end
        
    end

    methods (Access = public)
    
        function TimerUpdateFcn(app,src,event)
            % note: found a cheap solution by just putting varargin instead of obj, event etc.
            % I don't quite understand the proper way to implement this and I don't want to go through
            % the nitty gritty detials
            try
                app.UpdateX();
                app.UpdateY();
                app.UpdateZ();
            catch ME
                warning('Error while executing Timer Update');
                stop(app.updateTimer);
                start(app.updateTimer);
            end
            app.UpdateDisplayButton.Enable='on';

        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.ConnectX.Interruptible='off';app.ConnectY.Interruptible='off';app.ConnectZ.Interruptible='off';
            app.UIFigure.Interruptible='off';
            app.UIFigure.BusyAction='queue';
            app.updateTimer= timer('Period',1,'ExecutionMode','fixedSpacing',...
                                    'TasksToExecute',Inf,'BusyMode','queue');
            app.updateTimer.TimerFcn= {@app.TimerUpdateFcn};
            start(app.updateTimer);
        end

        % Value changed function: ConnectX
        function ConnectXPushed(app, event)
            value = app.ConnectX.Value;
            global keithleyX;
            if value
                keithleyX= Keithley2200('USB0::0x05E6::0x2200::9060029::INSTR');
            else
                if app.XOutputOnOff.Value
                    app.ConnectX.Value=1;
                    error('Cannot disconnect X while the instrument is ON')
                else
                    keithleyX.EndSession();
                    delete(keithleyX);
                end
            end
        end

        % Value changed function: ConnectY
        function ConnectYPushed(app, event)
            value = app.ConnectY.Value;
            global keithleyY;
            if value
                keithleyY= Keithley2200('USB0::0x05E6::0x2200::9104905::INSTR');
            else
                if app.YOutputOnOff.Value
                    app.ConnectY.Value=1;
                    error('Cannot disconnect Y while the instrument is ON');
                else
                    keithleyY.EndSession();
                    delete(keithleyY);
                end
            end
        end

        % Value changed function: ConnectZ
        function ConnectZPushed(app, event)
            value = app.ConnectZ.Value;
            global keithleyZ;
            if value
                keithleyZ= Keithley2200('USB0::0x05E6::0x2200::9104906::INSTR');
            else
                if app.ZOutputOnOff.Value
                    app.ConnectZ.Value=1;
                    error('Cannot disconnect Z while the instrument is ON');
                else
                   keithleyZ.EndSession();
                   delete(keithleyZ);
                end
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            global keithleyX;global keithleyY;global keithleyZ;
            choice=questdlg('All instruments will be switched OFF. Proceed?',...
                            'Close Experiment',...
                            'Yes','No','No');
            if strcmp(choice,'No')
                return
            end
            if app.ConnectX.Value
                keithleyX.OutputOnOff(0);
                keithleyX.EndSession();
                delete(keithleyX);
            end
            if app.ConnectY.Value
                keithleyY.OutputOnOff(0);
                keithleyY.EndSession();
                delete(keithleyY);
            end
            if app.ConnectZ.Value
                keithleyZ.OutputOnOff(0);
                keithleyZ.EndSession();
                delete(keithleyZ);
            end
            stop(app.updateTimer);
            delete(app.updateTimer);
            delete(app);
        end

        % Button pushed function: UpdateDisplayButton
        function UpdateDisplayPushed(app, event)
            app.UpdateX();
            app.UpdateY();
            app.UpdateZ();
        end

        % Button pushed function: XISet
        function XISetPushed(app, event)
            if not(app.ConnectX.Value)
                error('Not connected to the instrument X');
            end
            global keithleyX;
            keithleyX.ISet(app.XCurrentEdit.Value);
        end

        % Value changed function: XOutputOnOff
        function XOutputOnOffValueChanged(app, event)
            global keithleyX;
            value = app.XOutputOnOff.Value;
            if not(app.ConnectX.Value)
                app.XOutputOnOff.Value= not(value);
                updateXLamp();
                error('Not connected to the instrument X');
            end
            
            try
                keithleyX.OutputOnOff(value);
                updateXLamp();
            catch ME
                app.XOutputOnOff.Value=keithleyX.state;
                updateXLamp();
                rethrow(ME);
            end
            
            function updateXLamp()
                switch keithleyX.state
                    case 0
                        app.XLamp.Color=[0.9373 0.9373 0.9373];
                    case 1
                        app.XLamp.Color='red';
                end
            end
        end

        % Button pushed function: XVSet
        function XVSetPushed(app, event)
            if not(app.ConnectX.Value)
                error('Not connected to the instrument X');
            end
            global keithleyX;
            keithleyX.VSet(app.XVoltageEdit.Value);
        end

        % Button pushed function: YISet
        function YISetPushed(app, event)
            if not(app.ConnectY.Value)
                error('Not connected to the instrument Y');
            end
            global keithleyY;
            keithleyY.ISet(app.YCurrentEdit.Value);
        end

        % Value changed function: YOutputOnOff
        function YOutputOnOffValueChanged(app, event)
            global keithleyY;
            value = app.YOutputOnOff.Value;
            
            if not(app.ConnectY.Value)
                app.YOutputOnOff.Value= not(value);
                updateYLamp();
                error('Not connected to the instrument Y');
            end
            
            try
                keithleyY.OutputOnOff(value);
                updateYLamp();
            catch ME
                app.YOutputOnOff.Value=keithleyY.state;
                updateYLamp();
                rethrow(ME);
            end
            
            function updateYLamp()
                switch keithleyY.state
                    case 0
                        app.YLamp.Color=[0.9373 0.9373 0.9373];
                    case 1
                        app.YLamp.Color='red';
                end
            end
        end

        % Button pushed function: YVSet
        function YVSetPushed(app, event)
            if not(app.ConnectY.Value)
                error('Not connected to the instrument Y');
            end
            global keithleyY;
            keithleyY.VSet(app.YVoltageEdit.Value);
        end

        % Button pushed function: ZISet
        function ZISetPushed(app, event)
            if not(app.ConnectZ.Value)
                error('Not connected to instrument Z');
            end
            global keithleyZ;
            keithleyZ.ISet(app.ZCurrentEdit.Value);
        end

        % Value changed function: ZOutputOnOff
        function ZOutputOnOffValueChanged(app, event)
            global keithleyZ;
            value = app.ZOutputOnOff.Value;
            if not(app.ConnectZ.Value)
                app.ZOutputOnOff.Value= not(value);
                updateZLamp(Z);
                error('Not connected to instrument Z');
            end
            
            try
                keithleyZ.OutputOnOff(value);
                updateZLamp();
            catch ME
                app.ZOutputOnOff.Value=keithleyZ.state;
                updateZLamp();
                rethrow(ME);
            end
            
            function updateZLamp()
                switch keithleyZ.state
                    case 0
                        app.ZLamp.Color=[0.9373 0.9373 0.9373];
                    case 1
                        app.ZLamp.Color='red';
                end
            end
        end

        % Button pushed function: ZVSet
        function ZVSetPushed(app, event)
            if not(app.ConnectZ.Value)
                error('Not connected to instrument Z');
            end
            global keithleyZ;
            keithleyZ.VSet(app.ZVoltageEdit.Value);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ConnectX = uibutton(app.UIFigure, 'state');
            app.ConnectX.ValueChangedFcn = createCallbackFcn(app, @ConnectXPushed, true);
            app.ConnectX.Text = 'Connect X';
            app.ConnectY = uibutton(app.UIFigure, 'state');
            app.ConnectY.ValueChangedFcn = createCallbackFcn(app, @ConnectYPushed, true);
            app.ConnectY.Text = 'Connect Y';
            app.ConnectZ = uibutton(app.UIFigure, 'state');
            app.ConnectZ.ValueChangedFcn = createCallbackFcn(app, @ConnectZPushed, true);
            app.ConnectZ.Text = 'Connect Z';
            app.VoltageSetEditFieldLabel = uilabel(app.UIFigure);
            app.VoltageSetEditFieldLabel.Text = 'Voltage Set';
            app.XVoltageEdit = uieditfield(app.UIFigure, 'numeric');
            app.XVoltageEdit.Limits = [0 30];
            app.XVoltageEdit.ValueDisplayFormat = '%.3f';
            app.CurrentSetEditFieldLabel = uilabel(app.UIFigure);
            app.CurrentSetEditFieldLabel.Text = 'Current Set';
            app.XCurrentEdit = uieditfield(app.UIFigure, 'numeric');
            app.XCurrentEdit.Limits = [0 5];
            app.XCurrentEdit.ValueDisplayFormat = '%.3f';
            app.XVSet = uibutton(app.UIFigure, 'push');
            app.XVSet.ButtonPushedFcn = createCallbackFcn(app, @XVSetPushed, true);
            app.XVSet.Text = 'VSet';
            app.XISet = uibutton(app.UIFigure, 'push');
            app.XISet.ButtonPushedFcn = createCallbackFcn(app, @XISetPushed, true);
            app.XISet.Text = 'ISet';
            app.YVSet = uibutton(app.UIFigure, 'push');
            app.YVSet.ButtonPushedFcn = createCallbackFcn(app, @YVSetPushed, true);
            app.YVSet.Text = 'VSet';
            app.YISet = uibutton(app.UIFigure, 'push');
            app.YISet.ButtonPushedFcn = createCallbackFcn(app, @YISetPushed, true);
            app.YISet.Text = 'ISet';
            app.XVoltageEditField_2Label = uilabel(app.UIFigure);
            app.XVoltageEditField_2Label.Text = 'Voltage Set';
            app.YVoltageEdit = uieditfield(app.UIFigure, 'numeric');
            app.YVoltageEdit.Limits = [0 30];
            app.YVoltageEdit.ValueDisplayFormat = '%.3f';
            app.XCurrentEditField_2Label = uilabel(app.UIFigure);
            app.XCurrentEditField_2Label.Text = 'Current Set';
            app.YCurrentEdit = uieditfield(app.UIFigure, 'numeric');
            app.YCurrentEdit.Limits = [0 5];
            app.YCurrentEdit.ValueDisplayFormat = '%.3f';
            app.ZVSet = uibutton(app.UIFigure, 'push');
            app.ZVSet.ButtonPushedFcn = createCallbackFcn(app, @ZVSetPushed, true);
            app.ZVSet.Text = 'VSet';
            app.ZISet = uibutton(app.UIFigure, 'push');
            app.ZISet.ButtonPushedFcn = createCallbackFcn(app, @ZISetPushed, true);
            app.ZISet.Text = 'ISet';
            app.XVoltageEditField_3Label = uilabel(app.UIFigure);
            app.XVoltageEditField_3Label.Text = 'Voltage Set';
            app.ZVoltageEdit = uieditfield(app.UIFigure, 'numeric');
            app.ZVoltageEdit.Limits = [0 30];
            app.ZVoltageEdit.ValueDisplayFormat = '%.3f';
            app.XCurrentEditField_3Label = uilabel(app.UIFigure);
            app.XCurrentEditField_3Label.Text = 'Current Set';
            app.ZCurrentEdit = uieditfield(app.UIFigure, 'numeric');
            app.ZCurrentEdit.Limits = [0 5];
            app.ZCurrentEdit.ValueDisplayFormat = '%.3f';
            app.VoltageEditFieldLabel = uilabel(app.UIFigure);
            app.VoltageEditFieldLabel.Text = 'Voltage';
            app.XVoltage = uieditfield(app.UIFigure, 'numeric');
            app.XVoltage.Limits = [0 30];
            app.XVoltage.Editable = 'off';
            app.CurrentEditFieldLabel = uilabel(app.UIFigure);
            app.CurrentEditFieldLabel.Text = 'Current';
            app.XCurrent = uieditfield(app.UIFigure, 'numeric');
            app.XCurrent.Limits = [0 5];
            app.XCurrent.Editable = 'off';
            app.XOutputOnOff = uibutton(app.UIFigure, 'state');
            app.XOutputOnOff.ValueChangedFcn = createCallbackFcn(app, @XOutputOnOffValueChanged, true);
            app.XOutputOnOff.Text = 'Output On/Off';
            app.YOutputOnOff = uibutton(app.UIFigure, 'state');
            app.YOutputOnOff.ValueChangedFcn = createCallbackFcn(app, @YOutputOnOffValueChanged, true);
            app.YOutputOnOff.Text = 'Output On/Off';
            app.XVoltage_2Label = uilabel(app.UIFigure);
            app.XVoltage_2Label.Text = 'Voltage';
            app.YVoltage = uieditfield(app.UIFigure, 'numeric');
            app.YVoltage.Limits = [0 30];
            app.YVoltage.Editable = 'off';
            app.XCurrent_2Label = uilabel(app.UIFigure);
            app.XCurrent_2Label.Text = 'Current';
            app.YCurrent = uieditfield(app.UIFigure, 'numeric');
            app.YCurrent.Limits = [0 5];
            app.YCurrent.Editable = 'off';
            app.ZOutputOnOff = uibutton(app.UIFigure, 'state');
            app.ZOutputOnOff.ValueChangedFcn = createCallbackFcn(app, @ZOutputOnOffValueChanged, true);
            app.ZOutputOnOff.Text = 'Output On/Off';
            app.XVoltage_3Label = uilabel(app.UIFigure);
            app.XVoltage_3Label.Text = 'Voltage';
            app.ZVoltage = uieditfield(app.UIFigure, 'numeric');
            app.ZVoltage.Limits = [0 30];
            app.ZVoltage.Editable = 'off';
            app.XCurrent_3Label = uilabel(app.UIFigure);
            app.XCurrent_3Label.Text = 'Current';
            app.ZCurrent = uieditfield(app.UIFigure, 'numeric');
            app.ZCurrent.Limits = [0 5];
            app.ZCurrent.Editable = 'off';
            app.ModeEditFieldLabel = uilabel(app.UIFigure);
            app.ModeEditFieldLabel.Text = 'Mode';
            app.XMode = uieditfield(app.UIFigure, 'text');
            app.XMode.Editable = 'off';
            app.XMode_3Label = uilabel(app.UIFigure);
            app.XMode_3Label.Text = 'Mode';
            app.ZMode = uieditfield(app.UIFigure, 'text');
            app.ZMode.Editable = 'off';
            app.XMode_2Label = uilabel(app.UIFigure);
            app.XMode_2Label.Text = 'Mode';
            app.YMode = uieditfield(app.UIFigure, 'text');
            app.YMode.Editable = 'off';
            app.UpdateDisplayButton = uibutton(app.UIFigure, 'push');
            app.UpdateDisplayButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateDisplayPushed, true);
            app.UpdateDisplayButton.Text = 'Update';
            app.XLamp = uilamp(app.UIFigure);
            app.YLamp = uilamp(app.UIFigure);
            app.ZLamp = uilamp(app.UIFigure);
        end
    end

    methods (Access = public)

        % Construct app
        function app = Magnet_DO_NOT_USE

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end